classdef gauss < matlab.apps.AppBase
    %GAUSS Ergodic controller designer
    %   The app allows editing the Gaussian Mixture Model used for
    %   probability density in space for the Ergodic controller. The app
    %   outputs values Mu, Sigma, and alphas for all the Gaussians the user
    %   creates in the interface and saves them (if requested) in the
    %   *.mat/gauss_<unix_timestamp>.m format

    % Properties that correspond to app components
    properties (Access = public)
        ErgodiccontrollerdesignerUIFigure  matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenmodelMenu         matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        SaveandexitMenu       matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        HelpMenu              matlab.ui.container.Menu
        AboutMenu             matlab.ui.container.Menu
        Toolbar               matlab.ui.container.Toolbar
        PushTool              matlab.ui.container.toolbar.PushTool
        PushTool2             matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GridLayout8           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        GridLayout10          matlab.ui.container.GridLayout
        AddGaussianButton     matlab.ui.control.Button
        GridLayout9           matlab.ui.container.GridLayout
        RemoveGaussianButton  matlab.ui.control.Button
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        RightPanel2           matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        GuassiandataTab       matlab.ui.container.Tab
        GridLayout3           matlab.ui.container.GridLayout
        GridLayout12          matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        Gauge                 matlab.ui.control.LinearGauge
        GaugeLabel            matlab.ui.control.Label
        GridLayout11          matlab.ui.container.GridLayout
        EditField_10          matlab.ui.control.NumericEditField
        EditField_10Label     matlab.ui.control.Label
        EditField_9           matlab.ui.control.NumericEditField
        EditField_9Label      matlab.ui.control.Label
        alphaLabel            matlab.ui.control.Label
        GridLayout7           matlab.ui.container.GridLayout
        SaveButton            matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        sigmaLabel            matlab.ui.control.Label
        GridLayout5           matlab.ui.container.GridLayout
        EditField_8           matlab.ui.control.NumericEditField
        EditField_8Label      matlab.ui.control.Label
        EditField_7           matlab.ui.control.NumericEditField
        EditField_7Label      matlab.ui.control.Label
        EditField_6           matlab.ui.control.NumericEditField
        EditField_6Label      matlab.ui.control.Label
        EditField_5           matlab.ui.control.NumericEditField
        EditField_5Label      matlab.ui.control.Label
        GridLayout4           matlab.ui.container.GridLayout
        EditField_4           matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        EditField_3           matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        muxyLabel             matlab.ui.control.Label
        PlotTab               matlab.ui.container.Tab
        GridLayout13          matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        ParametersTab         matlab.ui.container.Tab
        GridLayout14          matlab.ui.container.GridLayout
        GridLayout7_2         matlab.ui.container.GridLayout
        SaveButton_2          matlab.ui.control.Button
        ResetButton_2         matlab.ui.control.Button
        GridLayout15_4        matlab.ui.container.GridLayout
        EditField_18          matlab.ui.control.NumericEditField
        EditField_18Label     matlab.ui.control.Label
        EditField_17          matlab.ui.control.NumericEditField
        EditField_17Label     matlab.ui.control.Label
        NLabel                matlab.ui.control.Label
        GridLayout15_3        matlab.ui.container.GridLayout
        EditField_16          matlab.ui.control.NumericEditField
        EditField_16Label     matlab.ui.control.Label
        EditField_15          matlab.ui.control.NumericEditField
        EditField_15Label     matlab.ui.control.Label
        mathbfx_fLabel        matlab.ui.control.Label
        GridLayout15_2        matlab.ui.container.GridLayout
        EditField_14          matlab.ui.control.NumericEditField
        EditField_14Label     matlab.ui.control.Label
        EditField_13          matlab.ui.control.NumericEditField
        EditField_13Label     matlab.ui.control.Label
        mathbfx_0Label        matlab.ui.control.Label
        GridLayout15          matlab.ui.container.GridLayout
        EditField_12          matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        EditField_11          matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        kLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mu          double
        Sigma       double
        alpha       double
        gauss_no    double
        model_no    int8
        file        string
        closed      string

        k           double
        K           double
        L           double
        N           double
        D           double
        x0          double
        xf          double
    end
    
    methods (Access = private)
        
        function update_list(app)
            lb_items=[];
            for k=1:length(app.alpha)
                lb_items=[lb_items strcat(string(k),". (",... 
                    string(app.Mu(1,k)),", ",...
                    string(app.Mu(2,k)),")")];
            end
            app.ListBox.Items=lb_items;
        end
        
        function save_model(app)
            if isempty(app.file)
               [file,path]=uiputfile(strcat("gauss_",...
                   string(round(posixtime(datetime))),".mat"));
            else
               [file,path]=uiputfile(app.file);
            end
            if isequal(file,0)||isequal(path,0)
                disp('file has not been saved')
            else
                Mu=app.Mu;
                Sigma=app.Sigma;
                alpha=app.alpha;
                app.file=fullfile(path,file);
                save(fullfile(path,file),"Mu","Sigma","alpha");
                disp(['model file has been saved to ',file])
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, optimal)
            file_list=dir("gauss_*.mat");
            
            try 
                file_list=file_list(end);
                load(fullfile(file_list.folder,file_list.name),"Sigma",...
                    "Mu","alpha","k","D","L","x0","xf");
                app.Mu=Mu;
                app.Sigma=Sigma;
                app.alpha=alpha;
                app.k=k;
                app.D=D;
                app.L=L;
                [app.K(1,:,:) app.K(2,:,:)]=ndgrid(0:1:app.k,0:1:app.k);
                app.x0=x0;
                app.xf=xf;
                app.file=fullfile(file_list.folder,file_list.name);

                msgbox(strcat("Found model in ",file_list.name," "),...
                    "Found model","help");
                disp(['model ' file_list.name ' has been loaded'])
            catch
                
                app.Mu(:,1)=[.33333;.14285];
                app.Mu(:,2)=[.83332;.1    ];
                app.Mu(:,3)=[.4    ;.57142];
                app.Mu(:,4)=[.83332;.85713];
                app.Sigma(:,:,1)=[.1;.1]*[.1;.1]'*1e-3+eye(2)*1e-3;
                app.Sigma(:,:,2)=app.Sigma(:,:,1);
                app.Sigma(:,:,3)=app.Sigma(:,:,1);
                app.Sigma(:,:,4)=app.Sigma(:,:,1);
                app.alpha=[.25;.25;.25;.25];
                app.k=9;
                app.D=2;
                app.L=2;
                app.N=500;
                [app.K(1,:,:) app.K(2,:,:)]=ndgrid(0:1:app.k,0:1:app.k);
                app.x0=[.1;.3];
                app.xf=[.5;.5];

            end
            
            if ~isempty(optimal)
                if optimal=="off"
                    app.EditField_15.Enable="off";
                    app.EditField_16.Enable="off";
                end
            end

            update_list(app);
        end

        % Close request function: ErgodiccontrollerdesignerUIFigure
        function ErgodiccontrollerdesignerUIFigureCloseRequest(app, event)
            if app.PushTool2.Enable=="on"
                answer=questdlg('Would you like to save the model?',...
	                'Save',...
	                'Save','Cancel','Save');
                switch answer
                case 'Save'
                    save_model(app);
                end
                
            end
            app.closed="yes";
            % delete(app)
            % delete is handled by the caller
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item=event.InteractionInformation.Item;
            
            if isempty(item)
                return
            end
            app.gauss_no=item;

            mu_x=app.EditField;
            mu_x.Enable="on";
            mu_y=app.EditField_2;
            mu_y.Enable="on";
            sigma_1=app.EditField_5;
            sigma_1.Enable="on";
            sigma_2=app.EditField_6;
            sigma_2.Enable="on";
            sigma_3=app.EditField_7;
            sigma_3.Enable="on";
            sigma_4=app.EditField_8;
            sigma_4.Enable="on";
            alpha=app.EditField_9;
            alpha.Enable="on";
            gauge=app.Gauge;
            gauge.Enable="on";

            app.ResetButton.Enable="on";
            app.SaveButton.Enable="on";

            mu_x.Value=app.Mu(1,item);
            mu_y.Value=app.Mu(2,item);
            sigma_1.Value=app.Sigma(1,1,item);
            sigma_2.Value=app.Sigma(2,1,item);
            sigma_3.Value=app.Sigma(1,2,item);
            sigma_4.Value=app.Sigma(2,2,item);
            alpha.Value=app.alpha(item);
            gauge.Value=app.alpha(item);
            
            if length(app.alpha)>1
                app.RemoveGaussianButton.Enable="on";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            mu_x=app.EditField;
            mu_y=app.EditField_2;
            sigma_1=app.EditField_5;
            sigma_2=app.EditField_6;
            sigma_3=app.EditField_7;
            sigma_4=app.EditField_8;
            alpha=app.EditField_9;
            gauge=app.Gauge;

            mu_x.Value=app.Mu(1,app.gauss_no);
            mu_y.Value=app.Mu(2,app.gauss_no);
            sigma_1.Value=app.Sigma(1,1,app.gauss_no);
            sigma_2.Value=app.Sigma(2,1,app.gauss_no);
            sigma_3.Value=app.Sigma(1,2,app.gauss_no);
            sigma_4.Value=app.Sigma(2,2,app.gauss_no);
            alpha.Value=app.alpha(app.gauss_no);
            gauge.Value=app.alpha(app.gauss_no);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            mu_x=app.EditField;
            mu_y=app.EditField_2;
            sigma_1=app.EditField_5;
            sigma_2=app.EditField_6;
            sigma_3=app.EditField_7;
            sigma_4=app.EditField_8;
            alpha=app.EditField_9;

            alpha_=app.alpha;
            alpha_(app.gauss_no)=alpha.Value;
            if sum(alpha_)>1
                msgbox(strcat("Sum of alphas must be one "),...
                    "Error","error");
                return;
            end
            if or(or(mu_x.Value>1,mu_x.Value<0),...
                  or(mu_y.Value>1,mu_y.Value<0))
                msgbox(strcat("All coordinates must be normalized "),...
                    "Error","error");
                return;
            end

            app.Mu(1,app.gauss_no)=mu_x.Value;
            app.Mu(2,app.gauss_no)=mu_y.Value;
            app.Sigma(1,1,app.gauss_no)=sigma_1.Value;
            app.Sigma(2,1,app.gauss_no)=sigma_2.Value;
            app.Sigma(1,2,app.gauss_no)=sigma_3.Value;
            app.Sigma(2,2,app.gauss_no)=sigma_4.Value;
            app.alpha(app.gauss_no)=alpha.Value;

            update_list(app);
            app.PushTool2.Enable="on";
            app.SaveMenu.Enable="on";
            app.SaveandexitMenu.Enable="on";
        end

        % Button pushed function: AddGaussianButton
        function AddGaussianButtonPushed(app, event)
            app.Mu=cat(2,app.Mu,[.0;.0]);
            app.Sigma=cat(3,app.Sigma,[.1;.1]*[.1;.1]'*1e-3+eye(2)*1e-3);
            app.alpha=[app.alpha;.0];

            update_list(app);

            app.PushTool2.Enable="on";
            app.SaveMenu.Enable="on";
            app.SaveandexitMenu.Enable="on";

            if app.TabGroup.SelectedTab.Title=="Plot"
                PlotTabButtonDown(app, event);
            end
        end

        % Button pushed function: RemoveGaussianButton
        function RemoveGaussianButtonPushed(app, event)
            if ~isempty(app.gauss_no)
                mu_x=app.EditField;
                mu_x.Enable="off";
                mu_y=app.EditField_2;
                mu_y.Enable="off";
                sigma_1=app.EditField_5;
                sigma_1.Enable="off";
                sigma_2=app.EditField_6;
                sigma_2.Enable="off";
                sigma_3=app.EditField_7;
                sigma_3.Enable="off";
                sigma_4=app.EditField_8;
                sigma_4.Enable="off";
                alpha=app.EditField_9;
                alpha.Enable="off";
                gauge=app.Gauge;
                gauge.Enable="off";

                app.ResetButton.Enable="off";
                app.SaveButton.Enable="off";

                app.Sigma(:,:,app.gauss_no)=[];
                app.Mu(:,app.gauss_no)=[];
                app.alpha(app.gauss_no)=[];

                update_list(app);
                app.PushTool2.Enable="on";
                app.SaveMenu.Enable="on";
                app.SaveandexitMenu.Enable="on";
            end
            app.RemoveGaussianButton.Enable="off";

            if app.TabGroup.SelectedTab.Title=="Plot"
                PlotTabButtonDown(app, event);
            end
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)

            save_model(app);
            app.PushTool2.Enable="off";
            app.SaveMenu.Enable="off";
            app.SaveandexitMenu.Enable="off";
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            [file,path]=uigetfile("*.mat");
            if isequal(file,0)||isequal(path,0)
                disp('file has not been opened')
            else
                try
                    load(fullfile(path,file),"Sigma","Mu","alpha","k","D",...
                        "L","x0","xf");
                    app.k=k;
                    app.D=D;
                    app.L=L;
                    [app.K(1,:,:) app.K(2,:,:)]=ndgrid(0:1:app.k,0:1:app.k);
                    app.x0=x0;
                    app.xf=xf;
                catch
                    load(fullfile(path,file),"Sigma","Mu","alpha");
                end
                
                app.Mu=Mu;
                app.Sigma=Sigma;
                app.alpha=alpha;

                disp(['model ' file ' has been loaded'])
                update_list(app);
                app.PushTool2.Enable="off";
                app.SaveMenu.Enable="off";
                app.SaveandexitMenu.Enable="off";
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox(sprintf(strcat("Ergodic controller designer ",...
                "\n\n",...
                "Version:\t  1.0 ","\n",...
                "Author:\t   Adam Seewald, Yale University ","\n",...
                "License:\t  CC BY-NC-SA 4.0 ")),...
                "Ergodic controller designer");
        end

        % Menu selected function: OpenmodelMenu
        function OpenmodelMenuSelected(app, event)
            PushToolClicked(app,event);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            PushTool2Clicked(app,event);
        end

        % Menu selected function: SaveandexitMenu
        function SaveandexitMenuSelected(app, event)
            save_model(app);
            app.closed="yes";
            % delete(app)
            % delete is handled by the caller
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            ErgodiccontrollerdesignerUIFigureCloseRequest(app,event);
        end

        % Value changed function: EditField_9
        function EditField_7ValueChanged(app, event)
            value=app.EditField_9.Value;
            app.Gauge.Value=value;
        end

        % Button down function: PlotTab
        function PlotTabButtonDown(app, event)
            xlim_=[-app.L/2 app.L/2];
            sampl=linspace(xlim_(1),xlim_(2),...
                length(app.K)^app.D); % build samples per each 
                                      % point in space
            [probx,proby]=ndgrid(sampl,sampl);
            probx=probx(:);
            proby=proby(:);
            f_k_val_prob=nan((length(app.K)^app.D)^2,1);
            f_k_val_prob_val=nan(length(app.K)^app.D,1);
            
            args.L=app.L;
            args.D=app.D;
            args.alpha=app.alpha;
            args.Mu=app.Mu;
            args.Sigma=app.Sigma;
            
            phi_k_val=nan(length(app.K)^app.D,1);

            for k=1:length(app.K)
                phi_k_val((k-1)*length(app.K)+1:k*length(app.K))=...
                    phi_k(app.K(:,:,k),args);
            end
            
            for j=1:(length(app.K)^app.D)^2
                for k=1:length(app.K)
                    f_k_val_prob_val((k-1)*length(app.K)+1:k*length(app.K))=...
                        f_k_x(app.K(:,:,k),[probx(j);proby(j)],args);
                end
                f_k_val_prob(j)=f_k_val_prob_val'*phi_k_val;
            end
            h=surf(app.UIAxes,...
                   reshape(probx,length(app.K)^app.D,length(app.K)^app.D),...
                   reshape(proby,length(app.K)^app.D,length(app.K)^app.D),...
                   reshape(f_k_val_prob,length(app.K)^app.D,length(app.K)^app.D),...
                   'FaceColor','interp','EdgeAlpha',0,'FaceAlpha',.7...
                  );
            z=get(h,'ZData');
            set(h,'ZData',z-10);
            xlim(app.UIAxes,[0 1]);
            ylim(app.UIAxes,[0 1]);
            app.UIAxes.YGrid='on';
            app.UIAxes.Layer='top';
            app.UIAxes.GridLineStyle=':';
            app.UIAxes.GridAlpha=.25;
        end

        % Button down function: ParametersTab
        function ParametersTabButtonDown(app, event)
            k=app.EditField_11;
            N=app.EditField_17;
            x0_1=app.EditField_13;
            x0_2=app.EditField_14;
            xf_1=app.EditField_15;
            xf_2=app.EditField_16;

            k.Value=app.k;
            app.K=[];
            [app.K(1,:,:) app.K(2,:,:)]=ndgrid(0:1:app.k,0:1:app.k);
            N.Value=app.N;
            x0_1.Value=app.x0(1);
            x0_2.Value=app.x0(2);
            xf_1.Value=app.xf(1);
            xf_2.Value=app.xf(2);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            ParametersTabButtonDown(app,event);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            k=app.EditField_11;
            N=app.EditField_17;
            x0_1=app.EditField_13;
            x0_2=app.EditField_14;
            xf_1=app.EditField_15;
            xf_2=app.EditField_16;

            app.k=k.Value;
            app.K=[];
            [app.K(1,:,:) app.K(2,:,:)]=ndgrid(0:1:app.k,0:1:app.k);
            app.N=N.Value;
            app.x0(1)=x0_1.Value;
            app.x0(2)=x0_2.Value;
            app.xf(1)=xf_1.Value;
            app.xf(2)=xf_2.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ErgodiccontrollerdesignerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ErgodiccontrollerdesignerUIFigure = uifigure('Visible', 'off');
            app.ErgodiccontrollerdesignerUIFigure.Name = 'Ergodic controller designer';
            app.ErgodiccontrollerdesignerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ErgodiccontrollerdesignerUIFigureCloseRequest, true);
            app.ErgodiccontrollerdesignerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.ErgodiccontrollerdesignerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenmodelMenu = uimenu(app.FileMenu);
            app.OpenmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenmodelMenuSelected, true);
            app.OpenmodelMenu.Accelerator = 'o';
            app.OpenmodelMenu.Text = 'Open model';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveandexitMenu = uimenu(app.FileMenu);
            app.SaveandexitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveandexitMenuSelected, true);
            app.SaveandexitMenu.Enable = 'off';
            app.SaveandexitMenu.Text = 'Save and exit';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.ErgodiccontrollerdesignerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.ErgodiccontrollerdesignerUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Open model file'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', 'open-icon.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save model to file'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Enable = 'off';
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon.png');
            app.GridLayout = uigridlayout(app.ErgodiccontrollerdesignerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.LeftPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout8);
            app.ListBoxLabel = uilabel(app.GridLayout6);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.RemoveGaussianButton = uibutton(app.GridLayout9, 'push');
            app.RemoveGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGaussianButtonPushed, true);
            app.RemoveGaussianButton.Enable = 'off';
            app.RemoveGaussianButton.Text = 'Remove Gaussian';
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.AddGaussianButton = uibutton(app.GridLayout10, 'push');
            app.AddGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @AddGaussianButtonPushed, true);
            app.AddGaussianButton.Text = 'Add Gaussian';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel2 = uipanel(app.RightPanel);
            app.GridLayout2 = uigridlayout(app.RightPanel2);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GuassiandataTab = uitab(app.TabGroup);
            app.GuassiandataTab.Title = 'Guassian data';
            app.GridLayout3 = uigridlayout(app.GuassiandataTab);
            app.muxyLabel = uilabel(app.GridLayout3);
            app.muxyLabel.Interpreter = 'latex';
            app.muxyLabel.Text = {''; ''; '\mu\, ($x, y$):'};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.EditFieldLabel = uilabel(app.GridLayout4);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.GridLayout4, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField_2Label = uilabel(app.GridLayout4);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_2.Enable = 'off';
            app.EditField_3Label = uilabel(app.GridLayout4);
            app.EditField_3Label.Enable = 'off';
            app.EditField_3Label.Visible = 'off';
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_3.Visible = 'off';
            app.EditField_4Label = uilabel(app.GridLayout4);
            app.EditField_4Label.Enable = 'off';
            app.EditField_4Label.Visible = 'off';
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Enable = 'off';
            app.EditField_4.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.EditField_5Label = uilabel(app.GridLayout5);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_5.Enable = 'off';
            app.EditField_6Label = uilabel(app.GridLayout5);
            app.EditField_6Label.Enable = 'off';
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_6.Enable = 'off';
            app.EditField_7Label = uilabel(app.GridLayout5);
            app.EditField_7Label.Text = '';
            app.EditField_7 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_7.Enable = 'off';
            app.EditField_8Label = uilabel(app.GridLayout5);
            app.EditField_8Label.Enable = 'off';
            app.EditField_8Label.Text = '';
            app.EditField_8 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_8.Enable = 'off';
            app.sigmaLabel = uilabel(app.GridLayout3);
            app.sigmaLabel.Interpreter = 'latex';
            app.sigmaLabel.Text = {''; ''; '\sigma:'};
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.GridLayout7, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.alphaLabel = uilabel(app.GridLayout3);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '\alpha:';
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.EditField_9Label = uilabel(app.GridLayout11);
            app.EditField_9Label.Text = '';
            app.EditField_9 = uieditfield(app.GridLayout11, 'numeric');
            app.EditField_9.Limits = [0 1];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_9.Enable = 'off';
            app.EditField_10Label = uilabel(app.GridLayout11);
            app.EditField_10Label.Enable = 'off';
            app.EditField_10Label.Visible = 'off';
            app.EditField_10Label.Text = '';
            app.EditField_10 = uieditfield(app.GridLayout11, 'numeric');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Enable = 'off';
            app.EditField_10.Visible = 'off';
            app.GridLayout12 = uigridlayout(app.GridLayout3);
            app.GaugeLabel = uilabel(app.GridLayout12);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.GridLayout12, 'linear');
            app.Gauge.Limits = [0 1];
            app.Gauge.Enable = 'off';
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotTab.ButtonDownFcn = createCallbackFcn(app, @PlotTabButtonDown, true);
            app.GridLayout13 = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.GridLayout13);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.ParametersTab.ButtonDownFcn = createCallbackFcn(app, @ParametersTabButtonDown, true);
            app.GridLayout14 = uigridlayout(app.ParametersTab);
            app.kLabel = uilabel(app.GridLayout14);
            app.kLabel.Interpreter = 'latex';
            app.kLabel.Text = '$k$:';
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.Label_2 = uilabel(app.GridLayout15);
            app.Label_2.Text = '';
            app.EditField_11 = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_11.Limits = [1 Inf];
            app.EditField_11.Value = 1;
            app.Label_3 = uilabel(app.GridLayout15);
            app.Label_3.Enable = 'off';
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.EditField_12 = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Enable = 'off';
            app.EditField_12.Visible = 'off';
            app.mathbfx_0Label = uilabel(app.GridLayout14);
            app.mathbfx_0Label.Interpreter = 'latex';
            app.mathbfx_0Label.Text = '$\mathbf{x}_0$:';
            app.GridLayout15_2 = uigridlayout(app.GridLayout14);
            app.EditField_13Label = uilabel(app.GridLayout15_2);
            app.EditField_13Label.Text = '';
            app.EditField_13 = uieditfield(app.GridLayout15_2, 'numeric');
            app.EditField_14Label = uilabel(app.GridLayout15_2);
            app.EditField_14Label.Text = '';
            app.EditField_14 = uieditfield(app.GridLayout15_2, 'numeric');
            app.mathbfx_fLabel = uilabel(app.GridLayout14);
            app.mathbfx_fLabel.Interpreter = 'latex';
            app.mathbfx_fLabel.Text = '$\mathbf{x}_f$:';
            app.GridLayout15_3 = uigridlayout(app.GridLayout14);
            app.EditField_15Label = uilabel(app.GridLayout15_3);
            app.EditField_15Label.Text = '';
            app.EditField_15 = uieditfield(app.GridLayout15_3, 'numeric');
            app.EditField_16Label = uilabel(app.GridLayout15_3);
            app.EditField_16Label.Text = '';
            app.EditField_16 = uieditfield(app.GridLayout15_3, 'numeric');
            app.NLabel = uilabel(app.GridLayout14);
            app.NLabel.Interpreter = 'latex';
            app.NLabel.Text = '$N$:';
            app.GridLayout15_4 = uigridlayout(app.GridLayout14);
            app.EditField_17Label = uilabel(app.GridLayout15_4);
            app.EditField_17Label.Text = '';
            app.EditField_17 = uieditfield(app.GridLayout15_4, 'numeric');
            app.EditField_18Label = uilabel(app.GridLayout15_4);
            app.EditField_18Label.Enable = 'off';
            app.EditField_18Label.Visible = 'off';
            app.EditField_18Label.Text = '';
            app.EditField_18 = uieditfield(app.GridLayout15_4, 'numeric');
            app.EditField_18.Editable = 'off';
            app.EditField_18.Enable = 'off';
            app.EditField_18.Visible = 'off';
            app.GridLayout7_2 = uigridlayout(app.GridLayout14);
            app.ResetButton_2 = uibutton(app.GridLayout7_2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.SaveButton_2 = uibutton(app.GridLayout7_2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            % Show the figure after all components are created
            app.ErgodiccontrollerdesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gauss(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ErgodiccontrollerdesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ErgodiccontrollerdesignerUIFigure)
        end
    end
end